classdef material < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BaseatomsPanel                  matlab.ui.container.Panel
        u2EditField                     matlab.ui.control.NumericEditField
        u2EditFieldLabel                matlab.ui.control.Label
        ScatteringLengthEditField       matlab.ui.control.NumericEditField
        ScatteringLengthEditFieldLabel  matlab.ui.control.Label
        zEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        barnsLabel                      matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        BravaisLatticePanel             matlab.ui.container.Panel
        a3Label                         matlab.ui.control.Label
        a2Label                         matlab.ui.control.Label
        a1Label                         matlab.ui.control.Label
        xEditField_4                    matlab.ui.control.NumericEditField
        zEditField_4                    matlab.ui.control.NumericEditField
        yEditField_4                    matlab.ui.control.NumericEditField
        xEditField_3                    matlab.ui.control.NumericEditField
        zEditField_3                    matlab.ui.control.NumericEditField
        yEditField_3                    matlab.ui.control.NumericEditField
        inAmstrongsLabel                matlab.ui.control.Label
        zEditField_2                    matlab.ui.control.NumericEditField
        zEditField_2Label               matlab.ui.control.Label
        yEditField_2                    matlab.ui.control.NumericEditField
        yEditField_2Label               matlab.ui.control.Label
        xEditField_2                    matlab.ui.control.NumericEditField
        xEditField_2Label               matlab.ui.control.Label
        CrystalSymmetryDropDown         matlab.ui.control.DropDown
        CrystalSymmetryDropDownLabel    matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        NameofFileEditField             matlab.ui.control.EditField
        NameofFileEditFieldLabel        matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
   % Leer el valor del campo de edición
    x = app.xEditField.Value; y = app.yEditField.Value; z = app.zEditField.Value; sl=app.ScatteringLengthEditField.Value; u2=app.u2EditField.Value;
     % Agregar la nueva fila al final de la tabla
    data = [app.UITable.Data; size(app.UITable.Data,1)+1 x y z sl u2];
    
    % Actualizar la tabla con los nuevos datos
    app.UITable.Data = data;
   
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if ~isempty(app.UITable.Selection)
            app.UITable.Data(unique(app.UITable.Selection(:,1)),:)=[];
            app.UITable.Data(:,1)=[1:size(app.UITable.Data,1)]';
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            if ~isempty(app.NameofFileEditField.Value)
      % Abre el archivo en modo de escritura
fileID = fopen([app.NameofFileEditField.Value '.txt'], 'w');

% Escribe
fprintf(fileID, '%s\n',app.CrystalSymmetryDropDown.Value);
fprintf(fileID, ' Red de Bravais \n');
fprintf(fileID, '%f %f %f \n',[app.xEditField_2.Value app.yEditField_2.Value app.zEditField_2.Value]);
fprintf(fileID, '%f %f %f \n',[app.xEditField_3.Value app.yEditField_3.Value app.zEditField_3.Value]);
fprintf(fileID, '%f %f %f \n',[app.xEditField_4.Value app.yEditField_4.Value app.zEditField_4.Value]);
fprintf(fileID, ' Base \n');
fprintf(fileID, '%f %f %f %f %f %f\n', app.UITable.Data');
 uialert(app.UIFigure, 'File created', 'Message');     
% Cierra el archivo
fclose(fileID);
            else
      uialert(app.UIFigure, 'Error: Name of the file not indicated', 'Error', 'Icon', 'error');          
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NameofFileEditFieldLabel = uilabel(app.UIFigure);
            app.NameofFileEditFieldLabel.Text = 'Name of File';
            app.NameofFileEditField = uieditfield(app.UIFigure, 'text');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.BravaisLatticePanel = uipanel(app.UIFigure);
            app.BravaisLatticePanel.Title = 'Bravais Lattice';
            app.CrystalSymmetryDropDownLabel = uilabel(app.BravaisLatticePanel);
            app.CrystalSymmetryDropDownLabel.Text = 'Crystal Symmetry';
            app.CrystalSymmetryDropDown = uidropdown(app.BravaisLatticePanel);
            app.CrystalSymmetryDropDown.Items = {'cubic', 'hexagonal', '', 'Option 4'};
            app.CrystalSymmetryDropDown.Value = 'cubic';
            app.xEditField_2Label = uilabel(app.BravaisLatticePanel);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.BravaisLatticePanel, 'numeric');
            app.yEditField_2Label = uilabel(app.BravaisLatticePanel);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.BravaisLatticePanel, 'numeric');
            app.zEditField_2Label = uilabel(app.BravaisLatticePanel);
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.BravaisLatticePanel, 'numeric');
            app.inAmstrongsLabel = uilabel(app.BravaisLatticePanel);
            app.inAmstrongsLabel.Text = 'in Amstrongs';
            app.yEditField_3 = uieditfield(app.BravaisLatticePanel, 'numeric');
            app.zEditField_3 = uieditfield(app.BravaisLatticePanel, 'numeric');
            app.xEditField_3 = uieditfield(app.BravaisLatticePanel, 'numeric');
            app.yEditField_4 = uieditfield(app.BravaisLatticePanel, 'numeric');
            app.zEditField_4 = uieditfield(app.BravaisLatticePanel, 'numeric');
            app.xEditField_4 = uieditfield(app.BravaisLatticePanel, 'numeric');
            app.a1Label = uilabel(app.BravaisLatticePanel);
            app.a1Label.Text = 'a1';
            app.a2Label = uilabel(app.BravaisLatticePanel);
            app.a2Label.Text = 'a2';
            app.a3Label = uilabel(app.BravaisLatticePanel);
            app.a3Label.Text = 'a3';
            app.BaseatomsPanel = uipanel(app.UIFigure);
            app.BaseatomsPanel.Title = 'Base atoms';
            app.Button = uibutton(app.BaseatomsPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.barnsLabel = uilabel(app.BaseatomsPanel);
            app.barnsLabel.Text = 'barns';
            app.UITable = uitable(app.BaseatomsPanel);
            app.UITable.ColumnName = {'Nro'; 'x'; 'y'; 'z'; 'scatt length'; 'u2'};
            app.UITable.RowName = {};
            app.Button_2 = uibutton(app.BaseatomsPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.xEditFieldLabel = uilabel(app.BaseatomsPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.BaseatomsPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.BaseatomsPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.BaseatomsPanel, 'numeric');
            app.zEditFieldLabel = uilabel(app.BaseatomsPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.BaseatomsPanel, 'numeric');
            app.ScatteringLengthEditFieldLabel = uilabel(app.BaseatomsPanel);
            app.ScatteringLengthEditFieldLabel.Text = 'Scattering Length';
            app.ScatteringLengthEditField = uieditfield(app.BaseatomsPanel, 'numeric');
            app.u2EditFieldLabel = uilabel(app.BaseatomsPanel);
            app.u2EditFieldLabel.Text = 'u2';
            app.u2EditField = uieditfield(app.BaseatomsPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = material

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end