classdef odf2BEI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        EditCheckBox                matlab.ui.control.CheckBox
        MaterialDropDown            matlab.ui.control.DropDown
        MaterialDropDownLabel       matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
        DefineBeamDirectionsButton  matlab.ui.control.Button
        LoadodfButton               matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        materialAPP % Propiedades con los datos del material
        v_exp_APP   % Propiedades con los datos de v_exp
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadodfButton
        function LoadodfButtonPushed(app, event)
            dir='C:\Users\mavic\MiguelAngel\Modelo de Seccion Eficaz Coherente\Caso AL7150\Datos EnginX\Datos de Flor\Aluminio\archivos\';
load([dir 'odf_Bolmaro_2022.mat']);

CS=crystalSymmetry('cubic'); 
SS=specimenSymmetry('triclinic');

figure(1);
h_Miller = [Miller(1,1,1,CS),Miller(2,0,0,CS),Miller(2,2,0,CS)];
plotPDF(odf8,h_Miller,'antipodal','silent')

        end

        % Button pushed function: DefineBeamDirectionsButton
        function DefineBeamDirectionsButtonPushed(app, event)
            app.v_exp_APP = v_exp; % Crear una nueva instancia de la app hija (sin .mlapp)
        end

        % Drop down opening function: MaterialDropDown
        function MaterialDropDownOpening(app, event)
            archivosTxt = dir('*.txt');
            app.MaterialDropDown.Items=['Select' 'New' {archivosTxt.name}];
        end

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged(app, event)
            app.EditCheckBox.Value=0;

            value = app.MaterialDropDown.Value;
            app.materialAPP = material; % Crear una nueva instancia de la app hija (sin .mlapp)
            app.materialAPP.UIFigure.Visible = 'off';
    
          if strcmp(value,'New')
            app.materialAPP.NameofFileEditField.Value ='';
          else
            app.materialAPP.NameofFileEditField.Value =erase(value,'.txt');
          end           
        end

        % Value changed function: EditCheckBox
        function EditCheckBoxValueChanged(app, event)
            if isvalid(app.materialAPP) 

            value = app.EditCheckBox.Value;
            if value

               app.materialAPP.UIFigure.Visible = 'on';
               filename=[app.materialAPP.NameofFileEditField.Value '.txt'];

               fileID=fopen(filename);
                    % Lee el archivo que define la red y sus bases
                    InputText=textscan(fileID,'%s',1,'delimiter','\n'); % Read header line

                    app.materialAPP.CrystalSymmetryDropDown.Value=InputText{1,1}{1};

                    InputText=textscan(fileID,'%s',1,'delimiter','\n'); % Read header line
                    RD=textscan(fileID,'%f %f %f',3,'delimiter','\n'); % Read header line
                    InputText=textscan(fileID,'%s',1,'delimiter','\n'); % Read header line
                    BS=textscan(fileID,'%f %f %f %f %f %f','delimiter','\n'); % Read header line
               M=cell2mat(RD); v0=abs(det(M));
               a1=[M(1,1) M(1,2) M(1,3)];
               a2=[M(2,1) M(2,2) M(2,3)];
               a3=[M(3,1) M(3,2) M(3,3)];

              fclose(fileID);
               app.materialAPP.xEditField_2.Value=a1(1); app.materialAPP.yEditField_2.Value=a1(2); app.materialAPP.zEditField_2.Value=a1(3);
               app.materialAPP.xEditField_3.Value=a2(1); app.materialAPP.yEditField_3.Value=a2(2); app.materialAPP.zEditField_3.Value=a2(3);
               app.materialAPP.xEditField_4.Value=a3(1); app.materialAPP.yEditField_4.Value=a3(2); app.materialAPP.zEditField_4.Value=a3(3);
               
               M=cell2mat(BS);
               app.materialAPP.UITable.Data=M;

            else
               app.materialAPP.UIFigure.Visible = 'off';
            end
            else
                app.MaterialDropDown.Value='Select';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadodfButton = uibutton(app.LeftPanel, 'push');
            app.LoadodfButton.ButtonPushedFcn = createCallbackFcn(app, @LoadodfButtonPushed, true);
            app.LoadodfButton.Text = 'Load odf';
            app.DefineBeamDirectionsButton = uibutton(app.LeftPanel, 'push');
            app.DefineBeamDirectionsButton.ButtonPushedFcn = createCallbackFcn(app, @DefineBeamDirectionsButtonPushed, true);
            app.DefineBeamDirectionsButton.Text = 'Define Beam Directions';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.Text = 'Run';
            app.MaterialDropDownLabel = uilabel(app.LeftPanel);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.LeftPanel);
            app.MaterialDropDown.Items = {'Select', 'New', 'Option 2', 'Option 3', 'Option 4'};
            app.MaterialDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MaterialDropDownOpening, true);
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
            app.MaterialDropDown.Value = 'Select';
            app.EditCheckBox = uicheckbox(app.LeftPanel);
            app.EditCheckBox.ValueChangedFcn = createCallbackFcn(app, @EditCheckBoxValueChanged, true);
            app.EditCheckBox.Text = 'Edit';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = odf2BEI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end