classdef v_exp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AnglesareindegreeLabel  matlab.ui.control.Label
        OKButton                matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        GraphCheckBox           matlab.ui.control.CheckBox
        BeamdirectionLabel      matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        Button                  matlab.ui.control.Button
        phiEditField            matlab.ui.control.NumericEditField
        phiEditFieldLabel       matlab.ui.control.Label
        rhoEditField            matlab.ui.control.NumericEditField
        rhoEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        v_exp_3d % vector3d con las orientacion del haz
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
              % Leer el valor del campo de edición
    newValue_phi = app.phiEditField.Value;
    newValue_rho = app.rhoEditField.Value;
     % Agregar la nueva fila al final de la tabla
    data = [app.UITable.Data; size(app.UITable.Data,1)+1 newValue_rho newValue_phi];
    
    % Actualizar la tabla con los nuevos datos
    app.UITable.Data = data;
   
        end

        % Value changed function: GraphCheckBox
        function GraphCheckBoxValueChanged(app, event)
            value = app.GraphCheckBox.Value;
if value
id_point={};
for ii=1:size(app.UITable.Data,1)
    id_point=[id_point, num2str(ii)];
    v_exp_3d(ii)=vector3d('polar',app.UITable.Data(ii,2)*degree,app.UITable.Data(ii,3)*degree);   
end
figure(2);
plot(v_exp_3d,'MarkerEdgeColor','k','MarkerFaceColor','w','Marker','s','label',id_point,'antipodal');
app.v_exp_3d=v_exp_3d;
else
    close(2); 
end
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             if ~isempty(app.UITable.Selection)
            app.UITable.Data(unique(app.UITable.Selection(:,1)),:)=[];
            app.UITable.Data(:,1)=[1:size(app.UITable.Data,1)]';
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
           for ii=1:size(app.UITable.Data,1)
           v_exp_3d(ii)=vector3d('polar',app.UITable.Data(ii,2)*degree,app.UITable.Data(ii,3)*degree);   
           end

            assignin('base', 'v_exp', v_exp_3d);
            delete(app); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.rhoEditFieldLabel = uilabel(app.UIFigure);
            app.rhoEditFieldLabel.Text = 'rho';
            app.rhoEditField = uieditfield(app.UIFigure, 'numeric');
            app.phiEditFieldLabel = uilabel(app.UIFigure);
            app.phiEditFieldLabel.Text = 'phi';
            app.phiEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Nro'; 'Phi'; 'Beta'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.BeamdirectionLabel = uilabel(app.UIFigure);
            app.BeamdirectionLabel.Text = 'Beam direction';
            app.GraphCheckBox = uicheckbox(app.UIFigure);
            app.GraphCheckBox.ValueChangedFcn = createCallbackFcn(app, @GraphCheckBoxValueChanged, true);
            app.GraphCheckBox.Text = 'Graph';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.AnglesareindegreeLabel = uilabel(app.UIFigure);
            app.AnglesareindegreeLabel.Text = 'Angles are in degree';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = v_exp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end